classdef fuzzy3out < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SISTEMMONITORINGBERBASISLOGIKAFUZZY  matlab.ui.Figure
        GridLayout    matlab.ui.container.GridLayout
        LeftPanel     matlab.ui.container.Panel
        INPUTPanel    matlab.ui.container.Panel
        TEMPLabel     matlab.ui.control.Label
        SUHU          matlab.ui.control.NumericEditField
        pHLabel       matlab.ui.control.Label
        pH            matlab.ui.control.NumericEditField
        TSSLabel      matlab.ui.control.Label
        TSS           matlab.ui.control.NumericEditField
        LabelSUHU     matlab.ui.control.Label
        LabelpH       matlab.ui.control.Label
        LabelTSS      matlab.ui.control.Label
        CLabel        matlab.ui.control.Label
        mgLLabel      matlab.ui.control.Label
        PROCESS       matlab.ui.control.Button
        RESET         matlab.ui.control.Button
        CLOSE         matlab.ui.control.Button
        RightPanel    matlab.ui.container.Panel
        OUTPUTPanel   matlab.ui.container.Panel
        hasil_1       matlab.ui.control.NumericEditField
        hasil_2       matlab.ui.control.NumericEditField
        hasil_3       matlab.ui.control.NumericEditField
        blower        matlab.ui.control.Label
        koagulan      matlab.ui.control.Label
        zat_ph        matlab.ui.control.Label
        blowersuhu    matlab.ui.control.Label
        zat_koagulan  matlab.ui.control.Label
        phzat         matlab.ui.control.Label
        Lamp          matlab.ui.control.Lamp
        Lamp_2        matlab.ui.control.Lamp
        Lamp_3        matlab.ui.control.Lamp
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.SISTEMMONITORINGBERBASISLOGIKAFUZZY.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {417, 417};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {320, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: PROCESS
        function PROCESSPushed(app, event)
            fis = readfis('3out');
            i1=app.SUHU.Value;
            i2=app.TSS.Value;
            i3=app.pH.Value;
            inputs=[i1 i2 i3];
            
            output = evalfis(inputs,fis);
            out1=output(1);
            out2=output(2);
            out3=output(3);
            
            app.hasil_1.Value=out1;
            if out1<=100 
	       out1='SLOW';
               app.Lamp.Color = 'GREEN';
            elseif out1 >= 85 && out1 <= 165 
               out1='MEDIUM';
               app.Lamp.Color = 'GREEN';
            else
	       out1='FAST';
               app.Lamp.Color = 'RED';
            end;
            
            app.hasil_2.Value=out2;
            if out2<=40
	       out2='LOW';
               app.Lamp_2.Color = 'GREEN';
            elseif out2 >= 30 && out2 <= 70 
               out2='NORMAL';
               app.Lamp_2.Color = 'GREEN';
            else
	       out2='HIGH';
               app.Lamp_2.Color = 'RED';
            end;
            
            app.hasil_3.Value=out3;
            if out3<=40
	       out3='ACID';
               app.Lamp_3.Color = 'YELLOW';
            elseif out3 >= 30 && out3 <= 70 
               out3='NEUTRAL';
               app.Lamp_3.Color = 'GREEN';
            else
	       out3='BASE';
               app.Lamp_3.Color = 'RED';
            end;
            
            app.blower.Text     = out1;
            app.koagulan.Text   = out2;
            app.zat_ph.Text     = out3;
        end

        % Value changed function: SUHU
        function SUHUValueChanged(app, event)
        suhu=app.SUHU.Value;
            if suhu <= 25
                suhu = 'LOW';    
            elseif suhu >= 15 && suhu <= 35
                suhu = 'NORMAL';
            else
                suhu = 'HIGH';
            end
            app.LabelSUHU.Text = suhu;
        
        end

        % Value changed function: TSS
        function TSSValueChanged(app, event)
        tss=app.TSS.Value;
            if tss <= 30
                 tss = 'LOW';
            elseif tss >= 20 && tss <= 50
                 tss = 'NORMAL';
            else
                 tss = 'HIGH';
            end
            app.LabelTSS.Text = tss;
        end

        % Value changed function: pH
        function pHValueChanged(app, event)
        ph=app.pH.Value;
            if ph<=6
	        ph = 'ACID';
            elseif ph >= 5 && ph <= 9
                ph = 'NEUTRAL';
            else
            	ph = 'BASE';
            end;
            app.LabelpH.Text = ph;
        end

        % Value changed function: hasil_1
        function hasil_1ValueChanged(app, event)
            Value1 = app.hasil_1.Value;
            Value2 = app.hasil_2.Value; %%teuing naon
            Value3 = app.hasil_3.Value; %%teuing naon
        end

        % Button pushed function: RESET
        function RESETButtonPushed(app, event)
            app.SUHU.Value  = [0];
            app.TSS.Value   = [0];
            app.pH.Value    = [0];
            
            app.LabelSUHU.Text  ='';
            app.LabelTSS.Text   ='';
            app.LabelpH.Text    ='';
            
            app.hasil_1.Value =[0];
            app.hasil_2.Value =[0];
            app.hasil_3.Value =[0];
            app.blower.Text     = '';
            app.koagulan.Text   = '';
            app.zat_ph.Text     = ''; 
            
            app.Lamp.Color   = [0.94,0.94,0.94]; %%code warna abu
            app.Lamp_2.Color = [0.94,0.94,0.94];
            app.Lamp_3.Color = [0.94,0.94,0.94];
        end

        % Button pushed function: CLOSE
        function CLOSEButtonPushed(app, event)
            closereq
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SISTEMMONITORINGBERBASISLOGIKAFUZZY = uifigure('Visible', 'off');
            app.SISTEMMONITORINGBERBASISLOGIKAFUZZY.Name = 'Mortgage Calculator';
            app.SISTEMMONITORINGBERBASISLOGIKAFUZZY.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.SISTEMMONITORINGBERBASISLOGIKAFUZZY);
            app.LeftPanel = uipanel(app.GridLayout);
            app.INPUTPanel = uipanel(app.LeftPanel);
            app.INPUTPanel.Title = 'INPUT';
            app.TEMPLabel = uilabel(app.INPUTPanel);
            app.TEMPLabel.Text = 'TEMP';
            app.SUHU = uieditfield(app.INPUTPanel, 'numeric');
            app.SUHU.ValueDisplayFormat = '%8.f';
            app.SUHU.ValueChangedFcn = createCallbackFcn(app, @SUHUValueChanged, true);
            app.pHLabel = uilabel(app.INPUTPanel);
            app.pHLabel.Text = 'pH';
            app.pH = uieditfield(app.INPUTPanel, 'numeric');
            app.pH.ValueDisplayFormat = '%.0f';
            app.pH.ValueChangedFcn = createCallbackFcn(app, @pHValueChanged, true);
            app.TSSLabel = uilabel(app.INPUTPanel);
            app.TSSLabel.Text = 'TSS';
            app.TSS = uieditfield(app.INPUTPanel, 'numeric');
            app.TSS.ValueChangedFcn = createCallbackFcn(app, @TSSValueChanged, true);
            app.LabelSUHU = uilabel(app.INPUTPanel);
            app.LabelSUHU.Text = '';
            app.LabelpH = uilabel(app.INPUTPanel);
            app.LabelpH.Text = '';
            app.LabelTSS = uilabel(app.INPUTPanel);
            app.LabelTSS.Text = '';
            app.CLabel = uilabel(app.INPUTPanel);
            app.CLabel.Text = 'C';
            app.mgLLabel = uilabel(app.INPUTPanel);
            app.mgLLabel.Text = 'mg/L';
            app.PROCESS = uibutton(app.LeftPanel, 'push');
            app.PROCESS.ButtonPushedFcn = createCallbackFcn(app, @PROCESSPushed, true);
            app.PROCESS.Text = 'PROCESS';
            app.RESET = uibutton(app.LeftPanel, 'push');
            app.RESET.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESET.Text = 'RESET';
            app.CLOSE = uibutton(app.LeftPanel, 'push');
            app.CLOSE.ButtonPushedFcn = createCallbackFcn(app, @CLOSEButtonPushed, true);
            app.CLOSE.Text = 'CLOSE';
            app.RightPanel = uipanel(app.GridLayout);
            app.OUTPUTPanel = uipanel(app.RightPanel);
            app.OUTPUTPanel.Title = 'OUTPUT';
            app.hasil_1 = uieditfield(app.OUTPUTPanel, 'numeric');
            app.hasil_1.ValueDisplayFormat = '%7.2f';
            app.hasil_1.ValueChangedFcn = createCallbackFcn(app, @hasil_1ValueChanged, true);
            app.hasil_1.Editable = 'off';
            app.hasil_2 = uieditfield(app.OUTPUTPanel, 'numeric');
            app.hasil_2.ValueDisplayFormat = '%7.2f';
            app.hasil_2.Editable = 'off';
            app.hasil_3 = uieditfield(app.OUTPUTPanel, 'numeric');
            app.hasil_3.ValueDisplayFormat = '%7.2f';
            app.hasil_3.Editable = 'off';
            app.blower = uilabel(app.OUTPUTPanel);
            app.blower.Text = '';
            app.koagulan = uilabel(app.OUTPUTPanel);
            app.koagulan.Text = '';
            app.zat_ph = uilabel(app.OUTPUTPanel);
            app.zat_ph.Text = '';
            app.blowersuhu = uilabel(app.OUTPUTPanel);
            app.blowersuhu.Text = 'BLOWER';
            app.zat_koagulan = uilabel(app.OUTPUTPanel);
            app.zat_koagulan.Text = 'COAGULANT';
            app.phzat = uilabel(app.OUTPUTPanel);
            app.phzat.Text = 'pH';
            app.Lamp = uilamp(app.OUTPUTPanel);
            app.Lamp_2 = uilamp(app.OUTPUTPanel);
            app.Lamp_3 = uilamp(app.OUTPUTPanel);
            % Show the figure after all components are created
            app.SISTEMMONITORINGBERBASISLOGIKAFUZZY.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fuzzy3out

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SISTEMMONITORINGBERBASISLOGIKAFUZZY)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SISTEMMONITORINGBERBASISLOGIKAFUZZY)
        end
    end
end